classdef TextHidingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SteganographyUIFigure     matlab.ui.Figure
        InputTextPanel            matlab.ui.container.Panel
        ConverttexttoImageButton  matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        UserTextEditFieldLabel    matlab.ui.control.Label
        UserTextEditField         matlab.ui.control.EditField
        HostImagePanel            matlab.ui.container.Panel
        BrowseHostImageButton     matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes2_2                 matlab.ui.control.UIAxes
        ResultsPanel              matlab.ui.container.Panel
        EmbedButton               matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        ExtractButton             matlab.ui.control.Button
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes3_2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        algoObject = algo; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConverttexttoImageButton
        function ConverttexttoImageButtonPushed(app, event)
            app.algoObject.text2Img(app.UserTextEditField.Value)
            imshow(app.algoObject.TextImage,'Parent', app.UIAxes);
        end

        % Button pushed function: BrowseHostImageButton
        function BrowseHostImageButtonPushed(app, event)
            % Allow only some image extensions for selection from dialog box
            [file,path] = uigetfile({'*.jpg';'*.jpeg';'*.png';'*.tiff';'*.bmp';'*.tif'});
            
            % If file is selected
            if ~isequal(file,0)
                app.algoObject.initializeSource(fullfile(path,file));
                                
                % Display image in UIAxes2
                imshow(fullfile(path,file),'Parent',app.UIAxes2);
                
                % Display image in UIAxes2_2
                imshow(app.algoObject.SourceImage,'Parent',app.UIAxes2_2);
            end
        end

        % Button pushed function: EmbedButton
        function EmbedButtonPushed(app, event)
            % Set Embedding Strength from input
            alpha = 0.75;
            
            % Embed the watermark
            app.algoObject.embed(alpha);
            
            % Display image in UIAxes3
            imshow(app.algoObject.WatermarkedImage,'Parent',app.UIAxes3);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Open Save Image dialog
            [fn, ext, ucancel] = imputfile;
            
            if ~isequal(ucancel,1)
                imwrite(app.algoObject.WatermarkedImage,fn,ext);
            end
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            % Fetch ExtractedImage Object
            ExtractedImage = app.algoObject.extract();
            
            % Display image in UIAxes3_2
            imshow(ExtractedImage,'Parent',app.UIAxes3_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SteganographyUIFigure = uifigure('Visible', 'off');
            app.SteganographyUIFigure.Name = 'Steganography';
            app.InputTextPanel = uipanel(app.SteganographyUIFigure);
            app.InputTextPanel.Title = 'Input Text';
            app.ConverttexttoImageButton = uibutton(app.InputTextPanel, 'push');
            app.ConverttexttoImageButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttexttoImageButtonPushed, true);
            app.ConverttexttoImageButton.Text = {'Convert text'; 'to Image'};
            app.UIAxes = uiaxes(app.InputTextPanel);
            title(app.UIAxes, 'Text Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UserTextEditFieldLabel = uilabel(app.InputTextPanel);
            app.UserTextEditFieldLabel.Text = {'User'; 'Text'};
            app.UserTextEditField = uieditfield(app.InputTextPanel, 'text');
            app.HostImagePanel = uipanel(app.SteganographyUIFigure);
            app.HostImagePanel.Title = 'Host Image';
            app.BrowseHostImageButton = uibutton(app.HostImagePanel, 'push');
            app.BrowseHostImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseHostImageButtonPushed, true);
            app.BrowseHostImageButton.Text = {'Browse '; 'Host Image'};
            app.UIAxes2 = uiaxes(app.HostImagePanel);
            title(app.UIAxes2, 'Host Image')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2_2 = uiaxes(app.HostImagePanel);
            title(app.UIAxes2_2, 'Grayscale')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.ResultsPanel = uipanel(app.SteganographyUIFigure);
            app.ResultsPanel.Title = 'Results';
            app.EmbedButton = uibutton(app.ResultsPanel, 'push');
            app.EmbedButton.ButtonPushedFcn = createCallbackFcn(app, @EmbedButtonPushed, true);
            app.EmbedButton.Text = 'Embed';
            app.SaveButton = uibutton(app.ResultsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ExtractButton = uibutton(app.ResultsPanel, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = 'Extract';
            app.UIAxes3 = uiaxes(app.ResultsPanel);
            title(app.UIAxes3, 'Result')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3_2 = uiaxes(app.ResultsPanel);
            title(app.UIAxes3_2, 'Extracted Image')
            xlabel(app.UIAxes3_2, '')
            ylabel(app.UIAxes3_2, '')
            % Show the figure after all components are created
            app.SteganographyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TextHidingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SteganographyUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SteganographyUIFigure)
        end
    end
end